classdef miniEye_ver0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        MessageSwitchLabel     matlab.ui.control.Label
        MessageSwitch          matlab.ui.control.Switch
        WholePlotButton        matlab.ui.control.Button
        SaccadesSwitchLabel    matlab.ui.control.Label
        SaccadesSwitch         matlab.ui.control.Switch
        FixationsSwitchLabel   matlab.ui.control.Label
        FixationsSwitch        matlab.ui.control.Switch
        dtype                  matlab.ui.control.DropDown
        DataTypeDropDownLabel  matlab.ui.control.Label
        ptype                  matlab.ui.control.DropDown
        PlottypeYLabel         matlab.ui.control.Label
        NexttrialButton        matlab.ui.control.Button
        LasttrialButton        matlab.ui.control.Button
        trial                  matlab.ui.control.NumericEditField
        TrialLabel             matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        EndtimeSlider          matlab.ui.control.Slider
        EndtimeSliderLabel     matlab.ui.control.Label
        StarttimeSlider        matlab.ui.control.Slider
        StarttimeSliderLabel   matlab.ui.control.Label
        Axes1                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        trial_property; % Trial number
        srt_property; % start time of the plot from a trial start (msec)
        end_property; % end time of the plot from a trial start (msec)
        ptype_property; % 1 - pupil plot, 2 - gaze x & y,
        dtype_property; % 1- raw data, 2 - raw with artifacts, 3 - clean data, 4 - clean and drift corrected data, 5 - baseline corrected, 6 - Nystrom cleaned data
        edf; % the main data structure, loaded from the workspace
        set; % the main analysis setting, loaded from the workspace
        cmap; % color code for messages
        sac_property; % does not show saccades, 0 (default); saccades on, 1;
        fix_property; % does not show fixation, 0 (default); show fixation, 1;
        msg_property; % does not show message, 0 (default); message on, 1;
        art_property; % plot which type of artifact: none, 0; blinks, 1
    end

    methods (Access = private)

        function plot_timecourse(app); % plot raw data
            cla(app.Axes1)

            p = app.edf.samples.pupil_size(:,app.set.eye); % pupil
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            X = app.edf.samples.x_deg(:,app.set.eye); % Gaze X
            Y = app.edf.samples.y_deg(:,app.set.eye); % Gaze Y

            % index of a trial
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            % start time of a trial
            srt_trial = time(ind_trial(1));

            % plot specific time window within a trial
            t1 = app.srt_property + srt_trial; % app.srt_property +
            t2 = app.end_property + srt_trial; % app.end_property +

            % find index of those times
            ind = find(time >= t1 & time <= t2);

            % find index of all the messages
            n_msg = max(app.edf.samples.msg);
            %             for ii = 1:n_msg
            %                 indm(ii,:) = find(app.edf.samples.msg == ii)';
            %             end
            indm = app.edf.events.msg.ind_srt;

            % find all saccades
            inds = find(app.edf.events.sac.trial == app.trial_property);
            sac_on = app.edf.events.sac.ind_srt(inds);
            sac_off = app.edf.events.sac.ind_end(inds);

            % find all fixations
            inds_fix = find(app.edf.events.fix.trial == app.trial_property);
            fix_on = app.edf.events.fix.ind_srt(inds_fix);
            fix_off = app.edf.events.fix.ind_end(inds_fix);

            % plot according to different types
            switch app.ptype_property
                case 1 % pupil
                    plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",2,'color',[0,0.447,0.741]);
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min(p(ind)),max(p(ind))])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Pupil Size')
                    %legend(app.Axes1,{'Pupil Size'})
                    title(app.Axes1,['Pupil time course: trial ',num2str(app.trial_property)])
                    legend(app.Axes1,'off');
                    %set(ZoomHandle,'Motion','horizontal')
                    % plot messages
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property %1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min(p(ind)),max(p(ind))],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max(p(ind))*0.9,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end

                    % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",6,'color',[0,0.447,0.741]);
                        end
                    end

                case 2 % gaze x & y
                    plot(app.Axes1,time(ind),X(ind),'LineWidth',2,'Color',[0, 0.4470, 0.7410]);
                    hold(app.Axes1,'on')
                    plot(app.Axes1,time(ind),Y(ind),'LineWidth',2,'Color',[0.8500, 0.3250, 0.0980]);
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Eye Position (deg)')
                    legend(app.Axes1,'off');
                    title(app.Axes1,['Gaze time course: trial ',num2str(app.trial_property)])
%                     if strcmp(app.msg_property,'On')
%                         for ii = app.trial_property%1:size(indm,1)
%                             for jj = 1:size(indm,2)
%                                 h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])],'LineStyle','--','Color',app.cmap(ii,:));
%                                 text(app.Axes1,time(indm(ii,jj)),max([X(ind);Y(ind)])*0.9,0,num2str(app.edf.events.msg.txt(ii,jj)));
%                             end
%                         end
%                     end
                    % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
                     % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end                    
            end
        end


        function results = plot_timecourse_blink(app)
            cla(app.Axes1)

            p = app.edf.samples.pupil_size(:,app.set.eye); % pupil
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            X = app.edf.samples.x_deg(:,app.set.eye); % Gaze X
            Y = app.edf.samples.y_deg(:,app.set.eye); % Gaze Y

            % find index of all the messages
            n_msg = max(app.edf.samples.msg);
            %             for ii = 1:n_msg
            %                 indm(ii,:) = find(app.edf.samples.msg == ii)';
            %             end
            indm = app.edf.events.msg.ind_srt;

            blink_ind = app.edf.trackloss.blink_ind;
            blink_of_ind = [app.edf.blink.onset_ind app.edf.blink.offset_ind];

                        % find all saccades
            inds = find(app.edf.events.sac.trial == app.trial_property);
            sac_on = app.edf.events.sac.ind_srt(inds);
            sac_off = app.edf.events.sac.ind_end(inds);

            % find all fixations
            inds_fix = find(app.edf.events.fix.trial == app.trial_property);
            fix_on = app.edf.events.fix.ind_srt(inds_fix);
            fix_off = app.edf.events.fix.ind_end(inds_fix);

            % index of a trial
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            % start time of a trial
            srt_trial = time(ind_trial(1));

            % plot specific time window within a trial
            t1 = app.srt_property + srt_trial;
            t2 = app.end_property + srt_trial;

            % find index of those times
            ind = find(time >= t1 & time <= t2);

            % plot according to different types
            switch app.ptype_property
                case 1 % pupil
                    plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",2,'color',[0,0.447,0.741]);
                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(blink_ind,ind)),p(intersect(blink_ind,ind)),20,'Marker',"*",'MarkerEdgeColor',[0.9290, 0.6940, 0.1250],'MarkerFaceColor','none');
                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(app.edf.blink.onset_ind,ind)),p(intersect(app.edf.blink.onset_ind,ind)),48,'Marker',"^",'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);

                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(app.edf.blink.offset_ind,ind)),p(intersect(app.edf.blink.offset_ind,ind)),48,'Marker',"v",'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                    % text(app.Axes1,time(intersect(blink_of_ind,ind)),p(intersect(blink_of_ind,ind)),app.edf.blink.num)
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min(p(ind)),max(p(ind))])
                    legend(app.Axes1,'off')
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Pupil Size')
                    title(app.Axes1,['Pupil time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min(p(ind)),max(p(ind))],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max(p(ind))*0.9,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end
                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",6,'color',[0,0.447,0.741]);
                        end
                    end

                case 2 % gaze x & y
                    plot(app.Axes1,time(ind),X(ind),'LineWidth',2,'Color',[0, 0.4470, 0.7410]);
                    hold(app.Axes1,'on')
                    plot(app.Axes1,time(ind),Y(ind),'LineWidth',2,'Color',[0.8500, 0.3250, 0.0980]);

                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(blink_ind,ind)),X(intersect(blink_ind,ind)),20,'Marker',"*",'MarkerEdgeColor',[0.9290, 0.6940, 0.1250],'MarkerFaceColor','none');
                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(app.edf.blink.onset_ind,ind)),X(intersect(app.edf.blink.onset_ind,ind)),48,'Marker',"^",'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Eye Position (deg)')
                    legend(app.Axes1,'off');
                    title(app.Axes1,['Gaze time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max([X(ind);Y(ind)])*0.9,0,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end

                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
                     % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
            end
        end

        function results = plot_timecourse_clean(app)
            cla(app.Axes1)

            p = app.edf.samples.pupil_size_clean(:,app.set.eye); % pupil
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            X = app.edf.samples.x_deg_clean(:,app.set.eye); % Gaze X
            Y = app.edf.samples.y_deg_clean(:,app.set.eye); % Gaze Y

            % index of a trial
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            % start time of a trial
            srt_trial = time(ind_trial(1));

            % plot specific time window within a trial
            t1 = app.srt_property + srt_trial;
            t2 = app.end_property + srt_trial;

            % find index of those times
            ind = find(time >= t1 & time <= t2);

            % find index of all the messages
            n_msg = max(app.edf.samples.msg);
            %             for ii = 1:n_msg
            %                 indm(ii,:) = find(app.edf.samples.msg == ii)';
            %             end
            indm = app.edf.events.msg.ind_srt;

                        % find all saccades
            inds = find(app.edf.events.sac.trial == app.trial_property);
            sac_on = app.edf.events.sac.ind_srt(inds);
            sac_off = app.edf.events.sac.ind_end(inds);

            % find all fixations
            inds_fix = find(app.edf.events.fix.trial == app.trial_property);
            fix_on = app.edf.events.fix.ind_srt(inds_fix);
            fix_off = app.edf.events.fix.ind_end(inds_fix);

            % plot according to different types
            switch app.ptype_property
                case 1 % pupil
                    plot(app.Axes1,time(ind),p(ind),'LineWidth',2,'Color',[0,0.447,0.741]);
                    xlim(app.Axes1,[t1 t2])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Pupil Size')
                    title(app.Axes1,['Pupil time course: trial ',num2str(app.trial_property)])
                    legend(app.Axes1,'off');
                    hold(app.Axes1,'on')
%                     if strcmp(app.msg_property,'On')
%                         for ii = app.trial_property%1:size(indm,1)
%                             for jj = 1:size(indm,2)
%                                 h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min(p(ind)),max(p(ind))],'LineStyle','--','Color',app.cmap(ii,:));
%                                 text(app.Axes1,time(indm(ii,jj)),max(p(ind))*0.9,num2str(app.edf.events.msg.txt(ii,jj)));
%                             end
%                         end
%                     end
                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",6,'color',[0,0.447,0.741]);
                        end
                    end

                case 2 % gaze x & y
                    plot(app.Axes1,time(ind),X(ind),'LineWidth',2,'Color',[0, 0.4470, 0.7410]);
                    hold(app.Axes1,'on')
                    plot(app.Axes1,time(ind),Y(ind),'LineWidth',2,'Color',[0.8500, 0.3250, 0.0980]);
                    xlim(app.Axes1,[t1 t2])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Eye Position (deg)')
                    legend(app.Axes1,'off');
                    title(app.Axes1,['Gaze time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
%                     if strcmp(app.msg_property,'On')
%                         for ii = app.trial_property%1:size(indm,1)
%                             for jj = 1:size(indm,2)
%                                 h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])],'LineStyle','--','Color',app.cmap(ii,:));
%                                 text(app.Axes1,time(indm(ii,jj)),max([X(ind);Y(ind)])*0.9,0,num2str(app.edf.events.msg.txt(ii,jj)));
%                             end
%                         end
%                     end

                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
                     % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
            end
        end

        function results = UpdatePlot(app)

            switch app.dtype_property
                case 1 % raw data
                    plot_timecourse(app);
                case 2 % raw data with blinks;
                    plot_timecourse_blink(app);
                case 3 % cleaned data;
                    plot_timecourse_clean(app);
                case 4 % cleaned and drift corrected data;
                    plot_timecourse_drift(app);
                case 5 % baseline-corrected data - not implemented for now
                    plot_timecourse_corr(app);
                case 6 % raw data with all artifacts
                    plot_timecourse_artifact(app);
            end

        end

        function plot_timecourse_drift(app)
            cla(app.Axes1)
            p = app.edf.samples.pupil_size_clean_drift(:,app.set.eye); % pupil
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            X = app.edf.samples.x_deg_clean_drift(:,app.set.eye); % Gaze X
            Y = app.edf.samples.y_deg_clean_drift(:,app.set.eye); % Gaze Y

            % find index of all the messages
            n_msg = max(app.edf.samples.msg);
            %             for ii = 1:n_msg
            %                 indm(ii,:) = find(app.edf.samples.msg == ii)';
            %             end
            indm = app.edf.events.msg.ind_srt;


            blink_ind = app.edf.trackloss.blink_ind;
            blink_of_ind = [app.edf.blink.onset_ind app.edf.blink.offset_ind];

                        % find all saccades - drift corrected (dc)
            inds = find(app.edf.events.sac_dc.trial == app.trial_property);
            sac_on = app.edf.events.sac_dc.ind_srt(inds);
            sac_off = app.edf.events.sac_dc.ind_end(inds);

                        % find all fixations - drift corrected (dc)
            inds_fix = find(app.edf.events.fix_dc.trial == app.trial_property);
            fix_on = app.edf.events.fix_dc.ind_srt(inds_fix);
            fix_off = app.edf.events.fix_dc.ind_end(inds_fix);

            % index of a trial
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            % start time of a trial
            srt_trial = time(ind_trial(1));

            % plot specific time window within a trial
            t1 = app.srt_property + srt_trial;
            t2 = app.end_property + srt_trial;

            % find index of those times
            ind = find(time >= t1 & time <= t2);

            % plot according to different types
            switch app.ptype_property
                case 1 % pupil
                    plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",2,'color',[0,0.447,0.741]);
                    hold(app.Axes1,'on')
                    % text(app.Axes1,time(intersect(blink_of_ind,ind)),p(intersect(blink_of_ind,ind)),app.edf.blink.num)
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min(p(ind)),max(p(ind))])
                    legend(app.Axes1,'off')
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Pupil Size')
                    title(app.Axes1,['Pupil time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min(p(ind)),max(p(ind))],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max(p(ind))*0.9,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end
                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",6,'color',[0,0.447,0.741]);
                        end
                    end

                case 2 % gaze x & y
                    plot(app.Axes1,time(ind),X(ind),'LineWidth',2,'Color',[0, 0.4470, 0.7410]);
                    hold(app.Axes1,'on')
                    plot(app.Axes1,time(ind),Y(ind),'LineWidth',2,'Color',[0.8500, 0.3250, 0.0980]);
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Eye Position (deg)')
                    legend(app.Axes1,'off');
                    title(app.Axes1,['Gaze time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max([X(ind);Y(ind)])*0.9,0,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end

                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
                     % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
            end
        end

        function plot_timecourse_corr(app)
            cla(app.Axes1)
            p = app.edf.samples.pupil_size_corr(:,app.set.eye); % pupil
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            X = app.edf.samples.x_deg_clean(:,app.set.eye); % Gaze X
            Y = app.edf.samples.y_deg_clean(:,app.set.eye); % Gaze Y

            % find index of all the messages
            n_msg = max(app.edf.samples.msg);
            %             for ii = 1:n_msg
            %                 indm(ii,:) = find(app.edf.samples.msg == ii)';
            %             end
            indm = app.edf.events.msg.ind_srt;

            % index of a trial
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            % start time of a trial
            srt_trial = time(ind_trial(1));

            % plot specific time window within a trial
            t1 = app.srt_property + srt_trial;
            t2 = app.end_property + srt_trial;

            % find index of those times
            ind = find(time >= t1 & time <= t2);

                        % find all saccades
            inds = find(app.edf.events.sac.trial == app.trial_property);
            sac_on = app.edf.events.sac.ind_srt(inds);
            sac_off = app.edf.events.sac.ind_end(inds);

                        % find all fixations
            inds_fix = find(app.edf.events.fix.trial == app.trial_property);
            fix_on = app.edf.events.fix.ind_srt(inds_fix);
            fix_off = app.edf.events.fix.ind_end(inds_fix);

            % plot according to different types
            switch app.ptype_property
                case 1 % pupil
                    plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",2,'color',[0,0.447,0.741]);
                    hold(app.Axes1,'on')
                    % text(app.Axes1,time(intersect(blink_of_ind,ind)),p(intersect(blink_of_ind,ind)),app.edf.blink.num)
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min(p(ind)),max(p(ind))])
                    legend(app.Axes1,'off')
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Pupil Size')
                    title(app.Axes1,['Pupil time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min(p(ind)),max(p(ind))],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max(p(ind))*0.9,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end
                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",6,'color',[0,0.447,0.741]);
                        end
                    end
                      % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end                   

                case 2 % gaze x & y
                    plot(app.Axes1,time(ind),X(ind),'LineWidth',2,'Color',[0, 0.4470, 0.7410]);
                    hold(app.Axes1,'on')
                    plot(app.Axes1,time(ind),Y(ind),'LineWidth',2,'Color',[0.8500, 0.3250, 0.0980]);
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Eye Position (deg)')
                    legend(app.Axes1,'off');
                    title(app.Axes1,['Gaze time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max([X(ind);Y(ind)])*0.9,0,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end

                                        % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                     % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
                    end

            end
        end

        function plot_timecourse_artifact(app)
            cla(app.Axes1)

            p = app.edf.samples.pupil_size(:,app.set.eye); % pupil
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            X = app.edf.samples.x_deg(:,app.set.eye); % Gaze X
            Y = app.edf.samples.y_deg(:,app.set.eye); % Gaze Y

            % find index of all the messages
            n_msg = max(app.edf.samples.msg);
            %             for ii = 1:n_msg
            %                 indm(ii,:) = find(app.edf.samples.msg == ii)';
            %             end
            indm = app.edf.events.msg.ind_srt;

            all_ind = app.edf.trackloss.all_ind;
            %            all_of_ind = [app.edf.blink.onset_ind app.edf.blink.offset_ind];

            % index of a trial
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            % start time of a trial
            srt_trial = time(ind_trial(1));

                        % find all saccades
            inds = find(app.edf.events.sac.trial == app.trial_property);
            sac_on = app.edf.events.sac.ind_srt(inds);
            sac_off = app.edf.events.sac.ind_end(inds);

                        % find all fixations
            inds_fix = find(app.edf.events.fix.trial == app.trial_property);
            fix_on = app.edf.events.fix.ind_srt(inds_fix);
            fix_off = app.edf.events.fix.ind_end(inds_fix);

            % plot specific time window within a trial
            t1 = app.srt_property + srt_trial;
            t2 = app.end_property + srt_trial;

            % find index of those times
            ind = find(time >= t1 & time <= t2);

            % plot according to different types
            switch app.ptype_property
                case 1 % pupil
                    plot(app.Axes1,time(ind),p(ind),'LineStyle',"-","LineWidth",2,'color',[0,0.447,0.741]);
                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(all_ind,ind)),p(intersect(all_ind,ind)),20,'Marker',"*",'MarkerEdgeColor',[0.9290, 0.6940, 0.1250],'MarkerFaceColor','none');
                    %                     hold(app.Axes1,'on')
                    %                     scatter(app.Axes1,time(intersect(app.edf.blink.onset_ind,ind)),p(intersect(app.edf.blink.onset_ind,ind)),48,'Marker',"^",'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                    %
                    %                     hold(app.Axes1,'on')
                    %                     scatter(app.Axes1,time(intersect(app.edf.blink.offset_ind,ind)),p(intersect(app.edf.blink.offset_ind,ind)),48,'Marker',"v",'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                    % text(app.Axes1,time(intersect(blink_of_ind,ind)),p(intersect(blink_of_ind,ind)),app.edf.blink.num)
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min(p(ind)),max(p(ind))])
                    legend(app.Axes1,'off')
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Pupil Size')
                    title(app.Axes1,['Pupil time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min(p(ind)),max(p(ind))],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max(p(ind))*0.9,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end

                case 2 % gaze x & y
                    plot(app.Axes1,time(ind),X(ind),'LineWidth',2,'Color',[0, 0.4470, 0.7410]);
                    hold(app.Axes1,'on')
                    plot(app.Axes1,time(ind),Y(ind),'LineWidth',2,'Color',[0.8500, 0.3250, 0.0980]);

                    hold(app.Axes1,'on')
                    scatter(app.Axes1,time(intersect(all_ind,ind)),X(intersect(all_ind,ind)),20,'Marker',"*",'MarkerEdgeColor',[0.9290, 0.6940, 0.1250],'MarkerFaceColor','none');
                    hold(app.Axes1,'on')
                    %                   scatter(app.Axes1,time(intersect(app.edf.blink.onset_ind,ind)),X(intersect(app.edf.blink.onset_ind,ind)),48,'Marker',"^",'MarkerEdgeColor',[0.4940, 0.1840, 0.5560]);
                    xlim(app.Axes1,[t1 t2])
                    ylim(app.Axes1,[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])])
                    xlabel(app.Axes1,'Time (msec)')
                    ylabel(app.Axes1,'Eye Position (deg)')
                    legend(app.Axes1,'off');
                    title(app.Axes1,['Gaze time course: trial ',num2str(app.trial_property)])
                    hold(app.Axes1,'on')
                    if strcmp(app.msg_property,'On')
                        for ii = app.trial_property%1:size(indm,1)
                            for jj = 1:size(indm,2)
                                h = line(app.Axes1,[time(indm(ii,jj)),time(indm(ii,jj))],[min([X(ind);Y(ind)]),max([X(ind);Y(ind)])],'LineStyle','--','Color',app.cmap(ii,:));
                                text(app.Axes1,time(indm(ii,jj)),max([X(ind);Y(ind)])*0.9,0,num2str(app.edf.events.msg.txt(ii,jj)));
                            end
                        end
                    end

                     % plot saccades
                    if strcmp(app.sac_property,'On')
                        for jj = 1:length(inds)
                            ind = sac_on(jj):sac_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
                     % plot fixations
                    if strcmp(app.fix_property,'On')
                        for jj = 1:length(inds_fix)
                            ind = fix_on(jj):fix_off(jj);
                            plot(app.Axes1,time(ind),X(ind),'LineWidth',6,'Color',[0, 0.4470, 0.7410]);
                            plot(app.Axes1,time(ind),Y(ind),'LineWidth',6,'Color',[0.8500, 0.3250, 0.0980]);
                        end
                    end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.edf = evalin('base','edf');
            app.set = evalin('base','set');
            app.trial_property = 1; % Trial number
            app.trial.Value = 1;
            app.ptype_property = 1; % pupil plot, 1; gaze, 2
            app.ptype.Value = '1';
            app.dtype_property = 1;
            app.dtype.Value = '1';
            app.cmap = flipud(autumn(8));
            app.msg_property = 'off';
            app.sac_property = 'off';
            app.fix_property = 'off';

            time = app.edf.samples.time - app.edf.samples.time(1); % time
            ind_trial = find(app.edf.samples.trial == app.trial_property);

            app.srt_property = 0; % start time of the plot from a trial start (msec)
            app.StarttimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.StarttimeSlider.Value = 0;

            app.end_property = time(ind_trial(end))-time(ind_trial(1)); % end time of the plot from a trial start (msec)
            app.EndtimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.EndtimeSlider.Value = time(ind_trial(end))-time(ind_trial(1));

            plot_timecourse(app);
        end

        % Value changed function: trial
        function trialValueChanged(app, event)
            app.trial_property = app.trial.Value;

            time = app.edf.samples.time - app.edf.samples.time(1); % time
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            app.StarttimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.EndtimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.StarttimeSlider.Value = 0;
            app.EndtimeSlider.Value = time(ind_trial(end))-time(ind_trial(1));

            UpdatePlot(app);
        end

        % Value changed function: ptype
        function ptypeValueChanged(app, event)
            app.ptype_property = str2num(app.ptype.Value);
            UpdatePlot(app);
        end

        % Value changed function: dtype
        function dtypeValueChanged(app, event)
            app.dtype_property = str2num(app.dtype.Value);
            UpdatePlot(app);
        end

        % Button pushed function: WholePlotButton
        function WholePlotButtonPushed(app, event)
            time = app.edf.samples.time - app.edf.samples.time(1); % time
            ind_trial = find(app.edf.samples.trial == app.trial_property);

            app.srt_property = 0; % start time of the plot from a trial start (msec)
            app.StarttimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.StarttimeSlider.Value = 0;

            app.end_property = time(ind_trial(end))-time(ind_trial(1)); % end time of the plot from a trial start (msec)
            app.EndtimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.EndtimeSlider.Value = time(ind_trial(end))-time(ind_trial(1));

            UpdatePlot(app);
        end

        % Button pushed function: LasttrialButton
        function LasttrialButtonPushed(app, event)
            if app.trial_property >= 2
                app.trial_property = app.trial_property - 1;
                app.trial.Value = app.trial_property;
            else
                disp('This is the first trial!')
            end

            time = app.edf.samples.time - app.edf.samples.time(1); % time
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            app.StarttimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.EndtimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.StarttimeSlider.Value = 0;
            app.EndtimeSlider.Value = time(ind_trial(end))-time(ind_trial(1))

            app.srt_property = 0; % start time of the plot from a trial start (msec)
            app.end_property = time(ind_trial(end))-time(ind_trial(1)); % end time of the plot from a trial start (msec)
            UpdatePlot(app);

        end

        % Button pushed function: NexttrialButton
        function NexttrialButtonPushed(app, event)
            if app.trial_property <= length(app.edf.trial.ind_srt) - 1
                app.trial_property = app.trial_property + 1;
                app.trial.Value = app.trial_property;
            else
                disp('This is the last trial!')
            end

            time = app.edf.samples.time - app.edf.samples.time(1); % time
            ind_trial = find(app.edf.samples.trial == app.trial_property);
            app.StarttimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.EndtimeSlider.Limits = [0,time(ind_trial(end))-time(ind_trial(1))];
            app.StarttimeSlider.Value = 0;
            app.EndtimeSlider.Value = time(ind_trial(end))-time(ind_trial(1))
            app.srt_property = 0; % start time of the plot from a trial start (msec)
            app.end_property = time(ind_trial(end))-time(ind_trial(1)); % end time of the plot from a trial start (msec)

            UpdatePlot(app);
        end

        % Value changed function: MessageSwitch
        function MessageSwitchValueChanged(app, event)
            value = app.MessageSwitch.Value;
            app.msg_property = value;
            UpdatePlot(app);
        end

        % Value changed function: StarttimeSlider
        function StarttimeSliderValueChanged(app, event)

            app.srt_property = app.StarttimeSlider.Value;
            UpdatePlot(app);
        end

        % Value changed function: EndtimeSlider
        function EndtimeSliderValueChanged(app, event)

            app.end_property = app.EndtimeSlider.Value;
            UpdatePlot(app);
        end

        % Value changed function: SaccadesSwitch
        function SaccadesSwitchValueChanged(app, event)
            value = app.SaccadesSwitch.Value;
            app.sac_property = value;
            UpdatePlot(app);
        end

        % Value changed function: FixationsSwitch
        function FixationsSwitchValueChanged(app, event)
            value = app.FixationsSwitch.Value;
            app.fix_property = value;
            UpdatePlot(app);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {607, 607};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {199, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TrialLabel = uilabel(app.LeftPanel);
            app.TrialLabel.Text = 'Trial #:';
            app.trial = uieditfield(app.LeftPanel, 'numeric');
            app.trial.Limits = [1 Inf];
            app.trial.ValueChangedFcn = createCallbackFcn(app, @trialValueChanged, true);
            app.trial.Value = 1;
            app.LasttrialButton = uibutton(app.LeftPanel, 'push');
            app.LasttrialButton.ButtonPushedFcn = createCallbackFcn(app, @LasttrialButtonPushed, true);
            app.LasttrialButton.Text = 'Last trial';
            app.NexttrialButton = uibutton(app.LeftPanel, 'push');
            app.NexttrialButton.ButtonPushedFcn = createCallbackFcn(app, @NexttrialButtonPushed, true);
            app.NexttrialButton.Text = 'Next trial';
            app.PlottypeYLabel = uilabel(app.LeftPanel);
            app.PlottypeYLabel.Text = 'Plot type (Y)';
            app.ptype = uidropdown(app.LeftPanel);
            app.ptype.Items = {'Pupil', 'Gaze'};
            app.ptype.ItemsData = {'1', '2', '', '', ''};
            app.ptype.ValueChangedFcn = createCallbackFcn(app, @ptypeValueChanged, true);
            app.ptype.Value = '1';
            app.DataTypeDropDownLabel = uilabel(app.LeftPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.dtype = uidropdown(app.LeftPanel);
            app.dtype.Items = {'Raw data', 'Raw data with blinks', 'Cleaned data', 'Drift corrected data', 'Baseline corrected data', 'Raw data with artifacts'};
            app.dtype.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.dtype.ValueChangedFcn = createCallbackFcn(app, @dtypeValueChanged, true);
            app.dtype.Value = '1';
            app.FixationsSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FixationsSwitch.ValueChangedFcn = createCallbackFcn(app, @FixationsSwitchValueChanged, true);
            app.FixationsSwitchLabel = uilabel(app.LeftPanel);
            app.FixationsSwitchLabel.Text = 'Fixations';
            app.SaccadesSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SaccadesSwitch.ValueChangedFcn = createCallbackFcn(app, @SaccadesSwitchValueChanged, true);
            app.SaccadesSwitchLabel = uilabel(app.LeftPanel);
            app.SaccadesSwitchLabel.Text = 'Saccades';
            app.WholePlotButton = uibutton(app.LeftPanel, 'push');
            app.WholePlotButton.ButtonPushedFcn = createCallbackFcn(app, @WholePlotButtonPushed, true);
            app.WholePlotButton.Text = 'Show the whole trial';
            app.MessageSwitch = uiswitch(app.LeftPanel, 'slider');
            app.MessageSwitch.ValueChangedFcn = createCallbackFcn(app, @MessageSwitchValueChanged, true);
            app.MessageSwitchLabel = uilabel(app.LeftPanel);
            app.MessageSwitchLabel.Text = 'Message';
            app.RightPanel = uipanel(app.GridLayout);
            app.Axes1 = uiaxes(app.RightPanel);
            xlabel(app.Axes1, 'Time (msec)')
            ylabel(app.Axes1, 'Data')
            app.StarttimeSliderLabel = uilabel(app.RightPanel);
            app.StarttimeSliderLabel.Text = 'Start time';
            app.StarttimeSlider = uislider(app.RightPanel);
            app.StarttimeSlider.ValueChangedFcn = createCallbackFcn(app, @StarttimeSliderValueChanged, true);
            app.EndtimeSliderLabel = uilabel(app.RightPanel);
            app.EndtimeSliderLabel.Text = 'End time';
            app.EndtimeSlider = uislider(app.RightPanel);
            app.EndtimeSlider.ValueChangedFcn = createCallbackFcn(app, @EndtimeSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniEye_ver0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end